classdef InvIPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultadosPanel                 matlab.ui.container.Panel
        UIAxes_invariante_segmentada    matlab.ui.control.UIAxes
        UIAxes_invariante_resultado     matlab.ui.control.UIAxes
        UIAxes_original_segmentada      matlab.ui.control.UIAxes
        UIAxes_original_resultado       matlab.ui.control.UIAxes
        InvariantedeIluminacinsobrePiezasMetlicasLabel  matlab.ui.control.Label
        GuardarresultadosButton         matlab.ui.control.Button
        EjecutarButton                  matlab.ui.control.Button
        NmerodecentrosEditField         matlab.ui.control.NumericEditField
        NmerodecentrosEditFieldLabel    matlab.ui.control.Label
        MtodoinvarianteButtonGroup      matlab.ui.container.ButtonGroup
        PCAButton                       matlab.ui.control.RadioButton
        MaddLightNavButton              matlab.ui.control.RadioButton
        UpcrofButton                    matlab.ui.control.RadioButton
        KrajnkButton                    matlab.ui.control.RadioButton
        MaddernButton                   matlab.ui.control.RadioButton
        AlvarezButton                   matlab.ui.control.RadioButton
        MtododeagrupamientoButtonGroup  matlab.ui.container.ButtonGroup
        InformacinespacialButton        matlab.ui.control.RadioButton
        GaussianMixturesButton          matlab.ui.control.RadioButton
        FuzzyCMeansButton               matlab.ui.control.RadioButton
        KMeansButton                    matlab.ui.control.RadioButton
        CargarimagenButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UIAxes_original                 matlab.ui.control.UIAxes
    end

    % Funciones auxiliares
    properties (Access = public)
        imagen
        imagen_tres_canales
        nombre_imagen
        extension
        tipo_agrup
        numClusters
        tipo
        tipo2
        imagen_original
        imagen_original_agrup
        imagen_resultado
        imagen_invariante_agrup
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes_original.Visible = "off";
            app.UIAxes_original_resultado.Visible = "off";
            app.UIAxes_original_segmentada.Visible = "off";
            app.UIAxes_invariante_resultado.Visible = "off";
            app.UIAxes_invariante_segmentada.Visible = "off";
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
            directorio_origen = '../Imagenes\Piezas\Camara-NikonCoolpixL830/';

            % Abro un cuadro de diálogo para seleccionar una imagen.
            [imagen_seleccionada, ruta] = uigetfile(fullfile(directorio_origen, '*.*'), 'Selecciona una imagen');

            % Extraigo el nombre y la extensión de la imagen seleccionada.
            [~, app.nombre_imagen, app.extension] = fileparts(imagen_seleccionada);
            
            % Leo la imagen desde su ruta completa.
            app.imagen = imread(fullfile(ruta, imagen_seleccionada));
            app.imagen_original = app.imagen;

            % Muestro la imagen dode corresponde
            app.Image.Visible = "off";
            imshow(app.imagen,'Parent',app.UIAxes_original);
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            % Función local para manejar imágenes de tres canales
            function imagenTresCanales = ImagenTresCanales(imagenOriginal)
                if size(imagenOriginal, 3) == 4
                    [R, G, B, ~] = imsplit(imagenOriginal);
                    imagenTresCanales = cat(3, R, G, B);
                else
                    imagenTresCanales = imagenOriginal;
                end
            end
            
            % Función local para obtener entradas de usuario con validación
            function [aux_str, aux] = aux_propio(mode, default)
                if mode == 1
                    prompt = ['Introduce el valor de aux (ej. ' num2str(default) '): '];
                    default_aux = default;
                elseif mode == 2
                    prompt = 'Introduce los valores de aux separados por comas (ej. 15,80,250): ';
                    default_aux = [15, 80, 250];
                else
                    error('Modo no soportado. Usa 1 para un solo valor o 2 para múltiples valores.');
                end
                user_input = input(prompt, 's');
                if isempty(user_input)
                    aux = default_aux;
                else
                    aux = str2num(user_input);
                    if isempty(aux)
                        aux = default_aux;
                        disp('Entrada inválida. Usando valores por defecto.');
                    elseif mode == 1 && length(aux) > 1
                        aux = aux(1);
                        disp('Se esperaba un solo valor. Usando el primer valor proporcionado.');
                    end
                end
                if mode == 1 && isscalar(aux)
                    aux = [aux];
                end
                aux_str = strcat('_', strjoin(arrayfun(@num2str, aux, 'UniformOutput', false), '_'));
            end
        
            % Función local para aplicar transformaciones invariantes
            function imagen_resultado = metodos_invariantes(opcion, imagen_tres_canales, aux)
                switch opcion
                    case 1
                        imagen_resultado = alvarez_transform(imagen_tres_canales, 45, aux);
                    case 2
                        imagen_resultado = maddern_transform(imagen_tres_canales, aux);
                    case 3
                        imagen_resultado = krajnik_transform(imagen_tres_canales, aux);
                    case 4
                        imagen_resultado = upcroft_transform(imagen_tres_canales, aux);
                    case 5
                        imagen_resultado = RGB2IlluminationInvariant(double(imagen_tres_canales) / 255, aux);
                    case 6
                        imagen_resultado = PCA(imagen_tres_canales);
                end
                imagen_resultado(imagen_resultado > 1) = 1; % General truncation for all cases
            end
        
            % Funciones locales para cada transformación específica
            function I_Alvarez = alvarez_transform(RGB, theta, alpha)
                R = double(RGB(:,:,1));
                G = double(RGB(:,:,2));
                B = double(RGB(:,:,3));
                RB_ratio = R ./ B;
                GB_ratio = G ./ B;
                log_approx_RB = alpha * ((RB_ratio / alpha) - 1);
                log_approx_GB = alpha * ((GB_ratio / alpha) - 1);
                I_Alvarez = cos(theta) * log_approx_RB + sin(theta) * log_approx_GB;
            end
        
            function I_Maddern = maddern_transform(RGB, alpha)
                R = double(RGB(:,:,1));
                G = double(RGB(:,:,2));
                B = double(RGB(:,:,3));
                I_Maddern = 0.5 + log(G) - alpha * log(B) - (1 - alpha) * log(R);
            end

            function I_Krajnik = krajnik_transform(RGB, theta)
                R = double(RGB(:,:,1));
                G = double(RGB(:,:,2));
                B = double(RGB(:,:,3));
                logR = log(R + 1);
                logG = log(G + 1);
                logB = log(B + 1);
                psi1 = logR - logG;
                psi2 = logG - logB;
                I_Krajnik = psi1 * cos(theta) + psi2 * sin(theta);
            end
            
            function I_Upcroft = upcroft_transform(RGB, alpha)
                R = double(RGB(:,:,1));
                G = double(RGB(:,:,2));
                B = double(RGB(:,:,3));
                I_Upcroft = log(G) - alpha * log(B) - (1 - alpha) * log(R);
            end
            
            function ii_image = RGB2IlluminationInvariant(image, alpha)
                R = double(image(:,:,1));
                G = double(image(:,:,2));
                B = double(image(:,:,3));
                ii_image = 0.5 + log(G) - alpha * log(B) - (1 - alpha) * log(R);
            end
            
            function imagenFinal = PCA(image)
                image = double(image) + 1;
                [h, w, ~] = size(image);
                sample_size = ceil(h * w / 100);
                sampled_pixels = zeros(3, sample_size);
                for z = 1:sample_size
                    x = randi(w); y = randi(h);
                    sampled_pixels(:, z) = reshape(image(y, x, :), [3 1]);
                end
                img_R = sampled_pixels(1, :);
                img_G = sampled_pixels(2, :);
                img_B = sampled_pixels(3, :);
                geoM = (img_R .* img_G .* img_B).^(1/3);
                x1 = log(img_R ./ geoM); % R/G
                x2 = log(img_B ./ geoM); % B/G
                X = [x1; x2]';
                [~, ~, V] = svd(X, 'econ');
                alpha = abs(atand(-V(1, 1) / V(2, 1)));
                rad_t = pi * (alpha - 1) / 180;
                grayImg = img_R * cos(rad_t) + img_B * sin(rad_t);
                % normalize
                tmp = grayImg;
                tmp = tmp - min(min(tmp));
                imagenFinal = tmp / max(max(tmp));
            end
            
            % Ahora incluyamos también las funciones de segmentación mencionadas:
            function [imgResultante, centers] = segmentar_imagen_KMeans(img, numClusters)
                [height, width, ~] = size(img);
                datosImg = double(reshape(img, [], 3));
                [membership, centers] = kmeans(datosImg, numClusters);
                colores = [255, 0, 0; 0, 255, 0; 0, 0, 255; 255, 255, 0; 0, 255, 255; 255, 0, 255; 255, 127, 0; 127, 0, 255; 0, 127, 255; 127, 255, 0];
                colores = colores(1:numClusters, :);
                imgResultante = zeros(height * width, 3);
                for k = 1:numClusters
                    idx = find(membership == k);
                    imgResultante(idx, :) = repmat(colores(k, :), length(idx), 1);
                end
                imgResultante = uint8(reshape(imgResultante, [height, width, 3]));
            end
            
            function [imgResultante, centers] = segmentar_imagen_fuzzy_CMeans(img, numClusters)
                [height, width, ~] = size(img);
                datosImg = double(reshape(img, [], 3));
            
                % Aplico Fuzzy C-Means
                [centers, U] = fcm(datosImg, numClusters);
            
                % Obtengo la membresía más alta para cada píxel
                [~, membership] = max(U, [], 1);
            
                % Defino una paleta de colores RGB
                colores = [255, 0, 0; 0, 255, 0; 0, 0, 255; 255, 255, 0; 0, 255, 255; 255, 0, 255; 255, 127, 0; 127, 0, 255; 0, 127, 255; 127, 255, 0];
                colores = colores(1:numClusters, :); % Asegurarse de tener suficientes colores
            
                % Creo imagen resultante
                imgResultante = zeros(height * width, 3); % Para formato RGB
                for k = 1:numClusters
                    idx = find(membership == k);
                    imgResultante(idx, :) = repmat(colores(k, :), length(idx), 1);
                end
            
                % Cambio la forma de la imagen resultante al formato original
                imgResultante = uint8(reshape(imgResultante, [height, width, 3]));
            end
    
            function [imgResultante, centers] = segmentar_imagen_GMM(img, numClusters)
                [height, width, ~] = size(img);
                datosImg = double(reshape(img, [], 3));
            
                % Aplico el Modelo de Mezclas Gaussianas con regularización
                % y especificando el tipo de covarianza
                gmm = fitgmdist(datosImg, numClusters, 'RegularizationValue', 1e-5, 'CovarianceType', 'diagonal');
            
                % Asigno a cada píxel al cluster más probable
                membership = cluster(gmm, datosImg);
                
                % Obtengo los centros (medias) de cada componente gaussiana
                centers = gmm.mu;
            
                % Defino una paleta de colores RGB
                colores = [255, 0, 0; 0, 255, 0; 0, 0, 255; 255, 255, 0; 0, 255, 255; 255, 0, 255; 255, 127, 0; 127, 0, 255; 0, 127, 255; 127, 255, 0];
                colores = colores(1:numClusters, :); % Asegurarse de tener suficientes colores
            
                % Creo imagen resultante
                imgResultante = zeros(height * width, 3); % Para formato RGB
                for k = 1:numClusters
                    idx = find(membership == k);
                    imgResultante(idx, :) = repmat(colores(k, :), length(idx), 1);
                end
            
                % Cambio la forma de la imagen resultante al formato original
                imgResultante = uint8(reshape(imgResultante, [height, width, 3]));
            end

            cla(app.UIAxes_original_resultado);
            cla(app.UIAxes_original_segmentada);
            cla(app.UIAxes_invariante_resultado);
            cla(app.UIAxes_invariante_segmentada);
            app.EjecutarButton.Text = 'Cargando...';

            app.imagen_tres_canales = ImagenTresCanales(app.imagen);
            % Calculo el invariante
            if app.AlvarezButton.Value == 1
                app.tipo = 'Alvarez';
                %[app.tipo2, aux] = aux_propio(1, .5);
                app.tipo2 = num2str(.5); aux = .5;
                app.imagen_resultado = metodos_invariantes(1, app.imagen_tres_canales, aux);
            elseif app.MaddernButton.Value == 1
                % Gran cantidad de ruido
                app.tipo = 'Maddern';
                %[app.tipo2, aux] = aux_propio(1, .48);
                app.tipo2 = num2str(.5); aux = .48;
                app.imagen_resultado = metodos_invariantes(2, app.imagen_tres_canales, aux);
            elseif app.KrajnkButton.Value == 1
                app.tipo = 'Krajnık';
                % de 0 a 180º y no hace falta truncar
                %[app.tipo2, aux] = aux_propio(1, 45);
                app.tipo2 = num2str(.5); aux = 45;
                app.imagen_resultado = metodos_invariantes(3, app.imagen_tres_canales, aux);
            elseif app.UpcrofButton.Value == 1
                app.tipo = 'Upcrof';
                % Depende mucho de la camara, con .9 se ve bien, con 2 muy bien 
                %[app.tipo2, aux] = aux_propio(1, 2);
                app.tipo2 = num2str(.5); aux = 2;
                app.imagen_resultado = metodos_invariantes(4, app.imagen_tres_canales, aux);
            elseif app.MaddLightNavButton.Value == 1
                % Will Maddern, Alexander D. Stewart, Colin McManus, Ben Upcroft, Winston Churchill, y Paul Newman
                app.tipo = 'MaddLightNav';
                %[app.tipo2, aux] = aux_propio(1, .2);
                app.tipo2 = num2str(.5); aux = .2;
                app.imagen_resultado = metodos_invariantes(5, app.imagen_tres_canales, aux);
            elseif app.PCAButton.Value == 1
                app.tipo = 'PCA';
                app.tipo2 = ''; aux = 0;
                app.imagen_resultado = metodos_invariantes(6, app.imagen_tres_canales, aux);
            end

            % Calculo la segmentación
            app.numClusters = app.NmerodecentrosEditField.Value;
            imagen_invariante_tres = cat(3, app.imagen_resultado, app.imagen_resultado, app.imagen_resultado);

            if app.KMeansButton.Value == 1
                app.tipo_agrup = 'K-Means';
                [app.imagen_original_agrup, ~] = segmentar_imagen_KMeans(app.imagen_tres_canales, app.numClusters);
                [app.imagen_invariante_agrup, ~] = segmentar_imagen_KMeans(imagen_invariante_tres, app.numClusters);
            elseif app.FuzzyCMeansButton.Value == 1
                app.tipo_agrup = 'C-Means';
                [app.imagen_original_agrup, ~] = segmentar_imagen_fuzzy_CMeans(app.imagen_tres_canales, app.numClusters);
                [app.imagen_invariante_agrup, ~] = segmentar_imagen_fuzzy_CMeans(imagen_invariante_tres, app.numClusters);
            elseif app.GaussianMixturesButton.Value == 1
                app.tipo_agrup = 'GMM';
                [app.imagen_original_agrup, ~] = segmentar_imagen_GMM(app.imagen_tres_canales, app.numClusters);
                [app.imagen_invariante_agrup, ~] = segmentar_imagen_GMM(imagen_invariante_tres, app.numClusters);
            elseif app.InformacinespacialButton.Value == 1
                app.tipo_agrup = 'Spatial';
                % TODO
            end

            % Muestro las imágenes
            imshow(app.imagen_original, 'Parent', app.UIAxes_original_resultado);
            imshow(app.imagen_original_agrup, 'Parent', app.UIAxes_original_segmentada);
            imshow(app.imagen_resultado, 'Parent', app.UIAxes_invariante_resultado);
            imshow(app.imagen_invariante_agrup, 'Parent', app.UIAxes_invariante_segmentada);
            app.EjecutarButton.Text = 'Ejecutar';
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            directorio_destino = '../Imagenes_resultado';
            ruta_completa = fullfile(directorio_destino, app.tipo);
            ruta_completa2 = fullfile(directorio_destino, 'Originales');
            
            imwrite(app.imagen_resultado, fullfile(ruta_completa, [app.nombre_imagen '_' app.tipo app.tipo2 app.extension]));
            imwrite(app.imagen_original_agrup, fullfile(ruta_completa2, [app.nombre_imagen '_Original_' app.tipo_agrup '_' num2str(app.numClusters) 'c.jpg']));
            imwrite(app.imagen_invariante_agrup, fullfile(ruta_completa, [app.nombre_imagen '_' app.tipo '_' app.tipo_agrup '_' num2str(app.numClusters) 'c.jpg']));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_original = uiaxes(app.UIFigure);
            title(app.UIAxes_original, 'Imagen original')
            app.UIAxes_original.XTick = [];
            app.UIAxes_original.XTickLabel = '';
            app.UIAxes_original.YTick = [];
            app.UIAxes_original.YTickLabel = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.MtododeagrupamientoButtonGroup = uibuttongroup(app.UIFigure);
            app.MtododeagrupamientoButtonGroup.Title = 'Método de agrupamiento';
            app.KMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.KMeansButton.Text = 'K-Means';
            app.KMeansButton.Value = true;
            app.FuzzyCMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.FuzzyCMeansButton.Text = 'Fuzzy C-Means';
            app.GaussianMixturesButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.GaussianMixturesButton.Text = 'Gaussian Mixtures';
            app.InformacinespacialButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.InformacinespacialButton.Text = 'Información espacial';
            app.MtodoinvarianteButtonGroup = uibuttongroup(app.UIFigure);
            app.MtodoinvarianteButtonGroup.Title = 'Método invariante';
            app.AlvarezButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.AlvarezButton.Text = 'Alvarez';
            app.AlvarezButton.Value = true;
            app.MaddernButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddernButton.Text = 'Maddern';
            app.KrajnkButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.KrajnkButton.Text = 'Krajnık';
            app.UpcrofButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.UpcrofButton.Text = 'Upcrof';
            app.MaddLightNavButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddLightNavButton.Text = 'MaddLightNav';
            app.PCAButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.NmerodecentrosEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodecentrosEditFieldLabel.Text = 'Número de centros';
            app.NmerodecentrosEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmerodecentrosEditField.Limits = [2 Inf];
            app.NmerodecentrosEditField.RoundFractionalValues = 'on';
            app.NmerodecentrosEditField.Value = 2;
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.GuardarresultadosButton = uibutton(app.UIFigure, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.InvariantedeIluminacinsobrePiezasMetlicasLabel = uilabel(app.UIFigure);
            app.InvariantedeIluminacinsobrePiezasMetlicasLabel.Text = 'Invariante de Iluminación sobre Piezas Metálicas';
            app.ResultadosPanel = uipanel(app.UIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.UIAxes_original_resultado = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_original_resultado, 'Imagen original')
            app.UIAxes_original_resultado.XTick = [];
            app.UIAxes_original_resultado.YTick = [];
            app.UIAxes_original_segmentada = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_original_segmentada, 'Original segmentada')
            zlabel(app.UIAxes_original_segmentada, 'Z')
            app.UIAxes_original_segmentada.XTick = [];
            app.UIAxes_original_segmentada.YTick = [];
            app.UIAxes_invariante_resultado = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_invariante_resultado, 'Imagen invariante')
            zlabel(app.UIAxes_invariante_resultado, 'Z')
            app.UIAxes_invariante_resultado.XTick = [];
            app.UIAxes_invariante_resultado.YTick = [];
            app.UIAxes_invariante_segmentada = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_invariante_segmentada, 'Invariante segmentada')
            zlabel(app.UIAxes_invariante_segmentada, 'Z')
            app.UIAxes_invariante_segmentada.XTick = [];
            app.UIAxes_invariante_segmentada.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InvIPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end